classdef MainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        Panel_2            matlab.ui.container.Panel
        StartButton        matlab.ui.control.Button
        PauseButton        matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        Label              matlab.ui.control.Label
        NextButton         matlab.ui.control.Button
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Label_2            matlab.ui.control.Label
        Switch_HL          matlab.ui.control.ToggleSwitch
        Switch_2Label      matlab.ui.control.Label
        Switch_HW          matlab.ui.control.ToggleSwitch
        Label_3            matlab.ui.control.Label
        Knob_H             matlab.ui.control.Knob
        Switch3Label       matlab.ui.control.Label
        Switch3            matlab.ui.control.Switch
        Knob_2Label        matlab.ui.control.Label
        Knob_L             matlab.ui.control.Knob
        Spinner_2Label     matlab.ui.control.Label
        Spinner_Born       matlab.ui.control.Spinner
        Tab_2              matlab.ui.container.Tab
        Label_4            matlab.ui.control.Label
        Spinner_Fps        matlab.ui.control.Spinner
        GIfSwitchLabel     matlab.ui.control.Label
        GIfSwitch          matlab.ui.control.Switch
        GIFEditFieldLabel  matlab.ui.control.Label
        GIFEditField       matlab.ui.control.EditField
        Label_5            matlab.ui.control.Label
        Knob_Fps           matlab.ui.control.DiscreteKnob
    end

    
    methods (Access = private)
        
        function [CellData,CellState] = CreateCellState(m,n,SurvalRate)
            %CREATECELLSTATE 创建元胞空间矩阵，并随机化初始状态
            %   CellData = CreateCellState(int m,int n,int SurvalRate)
            %   Input:
            %   m 行数
            %   n 列数
            %   SurvalRate 生成率 0 - 1
            %   Output:
            %   CellData 返回元胞空间矩阵
            
            %   GengGode_2019.03.03
            %   SixGird_V2.1
            
            CellDataTemp=rand(m,n);
            CellDataTemp(CellDataTemp<SurvalRate)=1;%小于出生率的生存
            CellDataTemp(CellDataTemp<1)=0;%其他的死亡
            CellData=logical(CellDataTemp);%逻辑化数组
            CellState=CellData;
            
        end
        
        function  Draw_Line(app,X,Y)
            %DRAW_LINE 在窗口中绘制六边形网格框架
            %   gcf = Drawing_Line(X,Y,gcf)
            %   Input：
            %   X
            %   Y
            %   gcf
            %   Output：
            %   gcf
            
            %   GengGode_2019.03.03
            %   SixGird_V2.0
            
            
            %hold on
            
            line(app.UIAxes,X,Y,'Color','black')%,'MarkerEdgeColor','y'
            
            set(app.UIAxes,'Color','b')
            axis off
            %hold off
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            m=app.Knob_H.Value;
            n=app.Knob_L.Value;
            SurvalRate=app.Spinner_Born.Value;
            %gcf=app.UIAxes
            %set(gcf,'Color','b')
            [CellData,CellState] = CreateCellState(m,n,SurvalRate);
            [X,Y]=CalculatedCoordinates_Cell(n,m);
            Drawing_Line(app,X,Y)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SixGird')
            xlabel(app.UIAxes, 'H')
            ylabel(app.UIAxes, 'L')
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '控制';
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.Panel_2, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Panel_2, 'push');
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.Panel_2);
            app.NextButton = uibutton(app.Panel_2, 'push');
            app.NextButton.Text = 'Next';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '初始化参数';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '紧邻方式';
            app.Switch_HL = uiswitch(app.Tab, 'toggle');
            app.Switch_HL.Items = {'Off - 0', 'On - 1'};
            app.Switch_HL.Value = 'Off - 0';
            app.Switch_2Label = uilabel(app.Tab);
            app.Switch_2Label.Text = '突出方式';
            app.Switch_HW = uiswitch(app.Tab, 'toggle');
            app.Switch_HW.Items = {'Off - 0', 'On - 1'};
            app.Switch_HW.Value = 'Off - 0';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '行元素数量';
            app.Knob_H = uiknob(app.Tab, 'continuous');
            app.Knob_H.Limits = [0 600];
            app.Knob_H.MajorTicks = [0 60 120 180 240 300 360 420 480 540 600];
            app.Knob_H.Value = 20;
            app.Switch3Label = uilabel(app.Tab);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.Tab, 'slider');
            app.Knob_2Label = uilabel(app.Tab);
            app.Knob_2Label.Text = '列元素数量';
            app.Knob_L = uiknob(app.Tab, 'continuous');
            app.Knob_L.Limits = [0 600];
            app.Knob_L.MajorTicks = [0 60 120 180 240 300 360 420 480 540 600];
            app.Knob_L.Value = 20;
            app.Spinner_2Label = uilabel(app.Tab);
            app.Spinner_2Label.Text = '出生率';
            app.Spinner_Born = uispinner(app.Tab);
            app.Spinner_Born.Step = 0.05;
            app.Spinner_Born.Limits = [0.001 1];
            app.Spinner_Born.Value = 0.05;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '运行参数';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '帧数';
            app.Spinner_Fps = uispinner(app.Tab_2);
            app.Spinner_Fps.Limits = [1 9999];
            app.Spinner_Fps.Value = 20;
            app.GIfSwitchLabel = uilabel(app.Tab_2);
            app.GIfSwitchLabel.Text = 'GIf录制';
            app.GIfSwitch = uiswitch(app.Tab_2, 'slider');
            app.GIFEditFieldLabel = uilabel(app.Tab_2);
            app.GIFEditFieldLabel.Text = 'GIF储存路径';
            app.GIFEditField = uieditfield(app.Tab_2, 'text');
            app.GIFEditField.Value = 'Test.gif';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '刷新等待时间';
            app.Knob_Fps = uiknob(app.Tab_2, 'discrete');
            app.Knob_Fps.Items = {'0 s', '0.5 s', '1 s', '3 s'};
            app.Knob_Fps.Value = '0 s';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end