classdef MainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SixGridGuiUIFigure  matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        Panel_2             matlab.ui.container.Panel
        StartButton         matlab.ui.control.Button
        PauseButton         matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        Label               matlab.ui.control.Label
        NextButton          matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Label_3             matlab.ui.control.Label
        Knob_H              matlab.ui.control.Knob
        DefaultSwitchLabel  matlab.ui.control.Label
        DefaultSwitch       matlab.ui.control.Switch
        Knob_2Label         matlab.ui.control.Label
        Knob_L              matlab.ui.control.Knob
        Tab_2               matlab.ui.container.Tab
        Label_4             matlab.ui.control.Label
        Spinner_Fps         matlab.ui.control.Spinner
        GIfSwitchLabel      matlab.ui.control.Label
        GIfSwitch           matlab.ui.control.Switch
        GIFEditFieldLabel   matlab.ui.control.Label
        GIFEditField        matlab.ui.control.EditField
        sLabel              matlab.ui.control.Label
        Knob_Fps            matlab.ui.control.DiscreteKnob
        Tab_3               matlab.ui.container.Tab
        Spinner_2Label      matlab.ui.control.Label
        Spinner_Born        matlab.ui.control.Spinner
        Switch_2Label       matlab.ui.control.Label
        Switch_HW           matlab.ui.control.ToggleSwitch
        Label_2             matlab.ui.control.Label
        Switch_HL           matlab.ui.control.ToggleSwitch
        X0SpinnerLabel      matlab.ui.control.Label
        X0Spinner           matlab.ui.control.Spinner
        Y0SpinnerLabel      matlab.ui.control.Label
        Y0Spinner           matlab.ui.control.Spinner
        Label_7             matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        Tab_4               matlab.ui.container.Tab
        TabGroup2           matlab.ui.container.TabGroup
        BSRuelsTab          matlab.ui.container.Tab
        BPanel              matlab.ui.container.Panel
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        SPanel              matlab.ui.container.Panel
        DropDown_3Label     matlab.ui.control.Label
        DropDown_3          matlab.ui.control.DropDown
        DropDown_4Label     matlab.ui.control.Label
        DropDown_4          matlab.ui.control.DropDown
        OtherTab            matlab.ui.container.Tab
    end


    properties (Access = private)
        XData % Description
        CellDataData % Description
        CellStateData % Description
        YData % Description
    end
    
    methods (Access = private)
        
        function [CellData,CellState] = CreateCell(app,m,n,SurvalRate)
            %CREATECELLSTATE 创建元胞空间矩阵，并随机化初始状态
            %   CellData = CreateCellState(int m,int n,int SurvalRate)
            %   Input:
            %   m 行数
            %   n 列数
            %   SurvalRate 生成率 0 - 1
            %   Output:
            %   CellData 返回元胞空间矩阵
            
            %   GengGode_2019.03.03
            %   SixGird_V2.1
            
            CellDataTemp=rand(m,n);
            CellDataTemp(CellDataTemp<SurvalRate)=1;%小于出生率的生存
            CellDataTemp(CellDataTemp<1)=0;%其他的死亡
            CellData=logical(CellDataTemp);%逻辑化数组
            CellState=CellData;
            
        end
        
        function  Draw_Line(app,X,Y)
            %DRAW_LINE 在窗口中绘制六边形网格框架
            %   gcf = Drawing_Line(X,Y,gcf)
            %   Input：
            %   X
            %   Y
            %   gcf
            %   Output：
            %   gcf
            
            %   GengGode_2019.03.03
            %   SixGird_V2.0
            
            
            %hold on
            
            line(app.UIAxes,X,Y,'Color','black')%,'MarkerEdgeColor','y'
            
            set(app.UIAxes,'Color','w')
            
            %hold off
        end
        
        function [X,Y] = CalculatedCoordinates_Cell(app,m,n,L,X0,Y0,HL,HW)
            %CALCULATEDCOORDINATES_CELL 计算六边形网格中各个六边形顶点坐标
            %   [X,Y] = CalculatedCoordinates_Cell(int m,int n,double L,double X0,double Y0,logical HL,logical HW)
            %   Input:
            %   m 行数
            %   n 列数
            %   L 边长
            %   X0 中心X坐标
            %   Y0 中心Y坐标
            %   HL 紧邻方式 0 行紧邻 1列紧邻
            %   HW 奇偶行突出 0 奇数行突出 1 偶数行突出
            %   Output：
            %   [X,Y] = {[7,m*n],[7,m*n]}
            %   返回值为两个矩阵，分别描述m*n个六边形的顶点X、Y坐标
            
            %   GengGode_2019.03.03
            %   SixGird_V2.1


            Ro3=sqrt(3);%预定义3的平方根
            % x=cell(m,n);%预定义的所有的六边形顶点的X
            % y=cell(m,n);%预定义的所有的六边形顶点的X
            t = [0 pi/3 2*pi/3 pi 4*pi/3 5*pi/3 2*pi;
                pi/6 pi/2 5*pi/6 7*pi/6 3*pi/2 11*pi/6 pi/6];
            %预定义计算六边形顶点的角度参数
            
            X_L=0:L:L*(m-1);%创建所有的六边形中心的X
            Y_L=0:L:L*(n-1);%创建所有的六边形中心的Y
            
            if HL
                x = L*sin(t(HL+1,:))+X_L(:)*1.5;
                y = L*cos(t(HL+1,:))+(Y_L(:)-1)*Ro3;
            else
                x = L*sin(t(HL+1,:))+(X_L(:)-1)*Ro3;
                y = L*cos(t(HL+1,:))+Y_L(:)*1.5;
            end
            
            x=(x+X0)';
            y=(y+Y0)';
            
            x=repmat(x,n,1);
            Y_T=repmat(y,1,m);
            X_T=reshape(x,7,m*n);
            
            [X_Add,Y_Add]=XY_Add(app,m,n,L,HL,HW);
            
            X=X_T+X_Add;
            Y=Y_T+Y_Add;
            
            %line(X,Y,'Color','black','MarkerEdgeColor','y')
            
        end
        
        function [X_Add,Y_Add]=XY_Add(app,m,n,L,HL,HW)
            %XY_ADD 计算六边形网格中错位偏移值
            %   [X_Add,Y_Add]=XY_Add(int m,int n,double L,logical HL,logical HW)
            %   Input:
            %   m 行数
            %   n 列数
            %   L 边长
            %   HL 紧邻方式 0 行紧邻 1列紧邻
            %   HW 奇偶行突出 0 奇数行突出 1 偶数行突出
            %   Output：
            %   [X_Add,Y_Add] = {[7,m*n],[7,m*n]}
            %   返回值为两个矩阵，分别描述m*n个六边形顶点X、Y坐标的错位偏移值
            
            %   GengGode_2019.03.03
            %   SixGird_V2.0
            
            Ro3=sqrt(3);
            Value_1=L*Ro3*0.75;
            Value_2=L*Ro3*0.25;
            Value=zeros(1,7)+Value_1;
            if HL
                %X_ADD
                X_Add=0;
                %Y_ADD
                if HW
                    %HW=1
                    y_t1=Value+Value_2;
                    y_t2=Value-Value_2;
                else
                    %HW=0
                    y_t1=Value-Value_2;
                    y_t2=Value+Value_2;
                end
                y_t1=repmat(y_t1,n,1);
                y_t2=repmat(y_t2,n,1);
                y_t=[y_t1;y_t2];
                if mod(m,2)==0
                    Y_Add=repmat(y_t,m/2,1)';
                else
                    Y_Add=repmat(y_t,(m-1)/2,1)';
                    Y_Add=[Y_Add y_t1'];
                end
                
            else
                %X_ADD
                if HW
                    %HW=1
                    x_t1=Value+Value_2;
                    x_t2=Value-Value_2;
                else
                    %HW=0
                    x_t1=Value-Value_2;
                    x_t2=Value+Value_2;
                end
                x_t=[x_t1;x_t2];
                if mod(n,2)==0
                    xl=repmat(x_t,n/2,1)';
                    if mod(n,2)==0
                        X_Add=repmat(xl,1,m);
                    else
                        X_Add=repmat(xl,1,m-1);
                        X_Add=[X_Add xl];
                    end
                else
                    xl=repmat(x_t,(n-1)/2,1)';
                    xl=[xl x_t1'];
                    if mod(m,2)==0
                        X_Add=repmat(xl,1,m);
                    else
                        X_Add=repmat(xl,1,m-1);
                        X_Add=[X_Add xl];
                    end
                end
                %Y_ADD
                Y_Add=0;
            end
        end
        
        
        function Draw_Patch(app,X,Y,CellData,CellState)
            %DRAWING_LINE 在窗口中绘制六边形网格框架
            %   gcf = Drawing_Line(X,Y,gcf)
            %   Input：
            %   X
            %   Y
            %   gcf
            %   Output：
            %   gcf
            
            %   GengGode_2019.03.03
            %   SixGird_V2.0
            
            %hold on
            
            % cla reset
            
            Value=numel(CellState);
            
            CellState=reshape(CellState,Value,1);
            CellData=reshape(CellData,Value,1);
            
            for i=1:Value
                if  CellState(i)
                    if CellData(i)
                        patch(app.UIAxes,X(:,i),Y(:,i),[0 0 0])
                        %                 line(X(:,i),Y(:,i),'Color','black')
                    else
                        patch(app.UIAxes,X(:,i),Y(:,i),[1 1 1])
                        %                 line(X(:,i),Y(:,i),'Color','w')
                    end
                end
                
                
                %     if CellData(i)
                %
                %         %patch(X(:,i),Y(:,i),[0 0 0])
                %         line(X(:,i),Y(:,i),'Color','black')
                %     end
                
            end
            set(app.UIAxes,'Color','w')
            %axis off
            %hold off
            
        end
        
        
        function DataPass(app,X,Y,CellData,CellState)
            app.XData=X;
            app.YData=Y;
            app.CellDataData=CellData;
            app.CellStateData=CellState;
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.UIAxes.cla();
            m=fix(app.Knob_H.Value);
            n=fix(app.Knob_L.Value);
            L=app.Spinner.Value;
            X0=app.X0Spinner.Value;
            Y0=app.Y0Spinner.Value;
            HL=str2double(app.Switch_HL.Value);
            HW=str2double(app.Switch_HW.Value);
            SurvalRate=app.Spinner_Born.Value;
            PauseTime=str2double(app.Knob_Fps.Value);
            FpsMast=fix(app.Spinner_Fps.Value);
            
            [CellData,CellState] = CreateCell(app,n,m,SurvalRate);
            [X,Y]=CalculatedCoordinates_Cell(app,n,m,L,X0,Y0,HL,HW);
            
            DataPass(app,X,Y,CellData,CellState)
            
            PauseButtonPushed(app, event)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            for i=1:100
            Draw_Line(app,app.XData,app.YData)
            Draw_Patch(app,app.XData,app.YData,app.CellDataData,app.CellStateData)
            
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SixGridGuiUIFigure = uifigure;
            app.SixGridGuiUIFigure.Name = 'Six Grid Gui';
            app.UIAxes = uiaxes(app.SixGridGuiUIFigure);
            title(app.UIAxes, 'SixGird')
            xlabel(app.UIAxes, 'H')
            ylabel(app.UIAxes, 'L')
            app.Panel_2 = uipanel(app.SixGridGuiUIFigure);
            app.Panel_2.Title = '控制';
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.Panel_2, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Panel_2, 'push');
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.Panel_2);
            app.NextButton = uibutton(app.Panel_2, 'push');
            app.NextButton.Text = 'Next';
            app.TabGroup = uitabgroup(app.SixGridGuiUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '初始化参数';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '行元素数量';
            app.Knob_H = uiknob(app.Tab, 'continuous');
            app.Knob_H.Limits = [0 600];
            app.Knob_H.MajorTicks = [0 60 120 180 240 300 360 420 480 540 600];
            app.Knob_H.Value = 20;
            app.DefaultSwitchLabel = uilabel(app.Tab);
            app.DefaultSwitchLabel.Text = 'Default';
            app.DefaultSwitch = uiswitch(app.Tab, 'slider');
            app.DefaultSwitch.Value = 'On';
            app.Knob_2Label = uilabel(app.Tab);
            app.Knob_2Label.Text = '列元素数量';
            app.Knob_L = uiknob(app.Tab, 'continuous');
            app.Knob_L.Limits = [0 600];
            app.Knob_L.MajorTicks = [0 60 120 180 240 300 360 420 480 540 600];
            app.Knob_L.Value = 20;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '运行参数';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '帧数';
            app.Spinner_Fps = uispinner(app.Tab_2);
            app.Spinner_Fps.Limits = [1 9999];
            app.Spinner_Fps.Value = 20;
            app.GIfSwitchLabel = uilabel(app.Tab_2);
            app.GIfSwitchLabel.Text = 'GIf录制';
            app.GIfSwitch = uiswitch(app.Tab_2, 'slider');
            app.GIFEditFieldLabel = uilabel(app.Tab_2);
            app.GIFEditFieldLabel.Text = 'GIF储存路径';
            app.GIFEditField = uieditfield(app.Tab_2, 'text');
            app.GIFEditField.Value = 'Test.gif';
            app.sLabel = uilabel(app.Tab_2);
            app.sLabel.Text = '刷新等待时间（s）';
            app.Knob_Fps = uiknob(app.Tab_2, 'discrete');
            app.Knob_Fps.Items = {'0.0', '0.5', '1.0', '3.0'};
            app.Knob_Fps.Value = '0.0';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '默认参数';
            app.Spinner_2Label = uilabel(app.Tab_3);
            app.Spinner_2Label.Text = '出生率';
            app.Spinner_Born = uispinner(app.Tab_3);
            app.Spinner_Born.Step = 0.05;
            app.Spinner_Born.Limits = [0.001 1];
            app.Spinner_Born.Value = 0.05;
            app.Switch_2Label = uilabel(app.Tab_3);
            app.Switch_2Label.Text = '突出方式';
            app.Switch_HW = uiswitch(app.Tab_3, 'toggle');
            app.Switch_HW.Items = {'0', '1'};
            app.Switch_HW.Value = '0';
            app.Label_2 = uilabel(app.Tab_3);
            app.Label_2.Text = '紧邻方式';
            app.Switch_HL = uiswitch(app.Tab_3, 'toggle');
            app.Switch_HL.Items = {'0', '1'};
            app.Switch_HL.Value = '0';
            app.X0SpinnerLabel = uilabel(app.Tab_3);
            app.X0SpinnerLabel.Text = 'X0';
            app.X0Spinner = uispinner(app.Tab_3);
            app.Y0SpinnerLabel = uilabel(app.Tab_3);
            app.Y0SpinnerLabel.Text = 'Y0';
            app.Y0Spinner = uispinner(app.Tab_3);
            app.Label_7 = uilabel(app.Tab_3);
            app.Label_7.Text = '边长';
            app.Spinner = uispinner(app.Tab_3);
            app.Spinner.Step = 0.1;
            app.Spinner.Value = 1;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '演化规则';
            app.TabGroup2 = uitabgroup(app.Tab_4);
            app.BSRuelsTab = uitab(app.TabGroup2);
            app.BSRuelsTab.Title = 'B/S Ruels';
            app.BPanel = uipanel(app.BSRuelsTab);
            app.BPanel.Title = 'B';
            app.DropDownLabel = uilabel(app.BPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.BPanel);
            app.DropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.DropDown.Value = '3';
            app.DropDown_2Label = uilabel(app.BPanel);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.BPanel);
            app.SPanel = uipanel(app.BSRuelsTab);
            app.SPanel.Title = 'S';
            app.DropDown_3Label = uilabel(app.SPanel);
            app.DropDown_3Label.Text = 'Drop Down';
            app.DropDown_3 = uidropdown(app.SPanel);
            app.DropDown_4Label = uilabel(app.SPanel);
            app.DropDown_4Label.Text = 'Drop Down';
            app.DropDown_4 = uidropdown(app.SPanel);
            app.OtherTab = uitab(app.TabGroup2);
            app.OtherTab.Title = 'Other';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SixGridGuiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SixGridGuiUIFigure)
        end
    end
end